classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        NeuralTab                matlab.ui.container.Tab
        CriareTreinarRedeButton  matlab.ui.control.Button
        ResultadosPanel          matlab.ui.container.Panel
        ResultadoLabel           matlab.ui.control.Label
        InputsButton             matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        TestEditField            matlab.ui.control.NumericEditField
        TestEditFieldLabel       matlab.ui.control.Label
        ValidateEditField        matlab.ui.control.NumericEditField
        ValidateEditFieldLabel   matlab.ui.control.Label
        TreinoEditField          matlab.ui.control.NumericEditField
        TreinoEditFieldLabel     matlab.ui.control.Label
        CamadasPanel             matlab.ui.container.Panel
        TopologiaEditField       matlab.ui.control.EditField
        DivisonDropDown          matlab.ui.control.DropDown
        DivisoDropDownLabel      matlab.ui.control.Label
        AtivaoOutputDropDown     matlab.ui.control.DropDown
        AtivaoOutputLabel        matlab.ui.control.Label
        TreinoDropDown           matlab.ui.control.DropDown
        TreinoDropDownLabel      matlab.ui.control.Label
        AtivaoEscondidaDropDown  matlab.ui.control.DropDown
        AtivaoEscondidaLabel     matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        EpochsSlider             matlab.ui.control.Slider
        EpochsSliderLabel        matlab.ui.control.Label
        FolderSelectorDropDown   matlab.ui.control.DropDown
        PastaLabel               matlab.ui.control.Label
        RelatrioTab              matlab.ui.container.Tab
        RealizaoTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriareTreinarRedeButton
        function CriareTreinarRedeButtonPushed(app, event)
            [matrizBinaria, targetMatrix] = tratamentoDeImagem(app.FolderSelectorDropDown.Value);

            epochsValue = app.EpochsSlider.Value;

            [topologia,flag] = str2num(app.TopologiaEditField.Value,"Evaluation","restricted");
            if ~flag || numel(topologia) == 0 || any(topologia(:) == 0)
                errordlg("Topologia não pode ser nula");
                return;
            end

            trainFuction = app.TreinoDropDown.Value;
            fuctionActivationHidden = app.AtivaoEscondidaDropDown.Value;
            fuctionActivationOutput = app.AtivaoOutputDropDown.Value;
            fuctionDivision = app.DivisonDropDown.Value;
            trainValue = app.TreinoEditField.Value;
            validateValue = app.ValidateEditField.Value;
            testValue = app.TestEditField.Value;
            
            [accuracy_global] = treinamentoDeRede(topologia,matrizBinaria,targetMatrix,epochsValue,trainFuction,fuctionActivationHidden, ...
                                                  fuctionActivationOutput,fuctionDivision,trainValue,validateValue,testValue)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NeuralTab = uitab(app.TabGroup);
            app.NeuralTab.Title = 'Neural';
            app.Panel = uipanel(app.NeuralTab);
            app.Panel.Title = 'Panel';
            app.PastaLabel = uilabel(app.Panel);
            app.PastaLabel.Text = 'Pasta';
            app.FolderSelectorDropDown = uidropdown(app.Panel);
            app.FolderSelectorDropDown.Items = {'start', 'test', 'train', 'extra'};
            app.FolderSelectorDropDown.Value = 'start';
            app.EpochsSliderLabel = uilabel(app.Panel);
            app.EpochsSliderLabel.Text = 'Epochs';
            app.EpochsSlider = uislider(app.Panel);
            app.EpochsSlider.Limits = [1 1000];
            app.EpochsSlider.MajorTicks = [1 200 400 600 800 1000];
            app.EpochsSlider.MinorTicks = [100 300 500 700 900];
            app.EpochsSlider.Value = 1;
            app.CamadasPanel = uipanel(app.NeuralTab);
            app.CamadasPanel.Title = 'Camadas';
            app.AtivaoEscondidaLabel = uilabel(app.CamadasPanel);
            app.AtivaoEscondidaLabel.Text = {'Ativação'; 'Escondida'};
            app.AtivaoEscondidaDropDown = uidropdown(app.CamadasPanel);
            app.AtivaoEscondidaDropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.AtivaoEscondidaDropDown.Value = 'tansig';
            app.TreinoDropDownLabel = uilabel(app.CamadasPanel);
            app.TreinoDropDownLabel.Text = 'Treino';
            app.TreinoDropDown = uidropdown(app.CamadasPanel);
            app.TreinoDropDown.Items = {'traingd', 'trainlm', 'traingdm', 'trainlm', 'trainbfg', 'trainbr'};
            app.TreinoDropDown.Value = 'traingd';
            app.AtivaoOutputLabel = uilabel(app.CamadasPanel);
            app.AtivaoOutputLabel.Text = {'Ativação'; 'Output'};
            app.AtivaoOutputDropDown = uidropdown(app.CamadasPanel);
            app.AtivaoOutputDropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.AtivaoOutputDropDown.Value = 'tansig';
            app.DivisoDropDownLabel = uilabel(app.CamadasPanel);
            app.DivisoDropDownLabel.Text = 'Divisão';
            app.DivisonDropDown = uidropdown(app.CamadasPanel);
            app.DivisonDropDown.Items = {'', 'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.DivisonDropDown.Value = 'dividerand';
            app.TopologiaEditField = uieditfield(app.CamadasPanel, 'text');
            app.Panel_2 = uipanel(app.NeuralTab);
            app.Panel_2.Title = 'Panel';
            app.TreinoEditFieldLabel = uilabel(app.Panel_2);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.TreinoEditField = uieditfield(app.Panel_2, 'numeric');
            app.TreinoEditField.Limits = [0 Inf];
            app.TreinoEditField.Value = 0.7;
            app.ValidateEditFieldLabel = uilabel(app.Panel_2);
            app.ValidateEditFieldLabel.Text = 'Validate';
            app.ValidateEditField = uieditfield(app.Panel_2, 'numeric');
            app.ValidateEditField.Value = 0.15;
            app.TestEditFieldLabel = uilabel(app.Panel_2);
            app.TestEditFieldLabel.Text = 'Test';
            app.TestEditField = uieditfield(app.Panel_2, 'numeric');
            app.TestEditField.Value = 0.15;
            app.InputsButton = uibutton(app.NeuralTab, 'push');
            app.InputsButton.Text = 'Inputs';
            app.ResultadosPanel = uipanel(app.NeuralTab);
            app.ResultadosPanel.Title = 'Resultados';
            app.ResultadoLabel = uilabel(app.ResultadosPanel);
            app.ResultadoLabel.Text = 'Resultado';
            app.CriareTreinarRedeButton = uibutton(app.NeuralTab, 'push');
            app.CriareTreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriareTreinarRedeButtonPushed, true);
            app.CriareTreinarRedeButton.Text = 'Criar e Treinar Rede';
            app.RelatrioTab = uitab(app.TabGroup);
            app.RelatrioTab.Title = 'Relatório';
            app.RealizaoTab = uitab(app.TabGroup);
            app.RealizaoTab.Title = 'Realização';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end