classdef desenho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EscolherRede            matlab.ui.control.DropDown
        EscolherRedeLabel       matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        ResultadoPanel          matlab.ui.container.Panel
        Resultado               matlab.ui.control.Label
        ClassificarButton       matlab.ui.control.Button
        NomeRede                matlab.ui.control.EditField
        NomeRedeEditFieldLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        grayImage = ones(224,224);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list = dir('Out\Redes\B_Best\*.mat');

            app.EscolherRede.Items = {list.name};
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            hFH = drawfreehand(app.UIAxes);

            % Get the xy coordinates of where they drew.
            xy = hFH.Position;
            % get rid of imfreehand remnant.
            delete(hFH);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(app.UIAxes, xCoordinates, yCoordinates, 'ro-', 'LineWidth', 2);

            promptMessage = sprintf('Deseja utilizar esta imagem?');
            titleBarCaption = 'Continuar';
            button = questdlg(promptMessage, titleBarCaption, 'OK', 'Não', 'OK');
            if strcmpi(button, 'Não')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                return;
            end
            

            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                app.grayImage(row, column) = 0;
            end
           
            app.grayImage = flipud(app.grayImage);
            %imshow(app.grayImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: ClassificarButton
        function ClassificarButtonPushed(app, event)
            if(app.NomeRede.Value ~= "")
                app.Resultado.Text = sprintf("Imagem Identificada = %s",identificarImagem(app.NomeRede.Value , "null" , app.grayImage));
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.Resultado.Text = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Clique e arraste com o botão esquerdo do rato para desenhar')
            app.UIAxes.XLim = [0 224];
            app.UIAxes.YLim = [0 224];
            app.UIAxes.ZLim = [0 224];
            app.UIAxes.XTick = 0.224;
            app.UIAxes.YTick = 0.224;
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NomeRedeEditFieldLabel = uilabel(app.UIFigure);
            app.NomeRedeEditFieldLabel.Text = 'Nome Rede';
            app.NomeRede = uieditfield(app.UIFigure, 'text');
            app.ClassificarButton = uibutton(app.UIFigure, 'push');
            app.ClassificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButtonPushed, true);
            app.ClassificarButton.Text = 'Classificar';
            app.ResultadoPanel = uipanel(app.UIFigure);
            app.ResultadoPanel.Title = 'Resultado';
            app.Resultado = uilabel(app.ResultadoPanel);
            app.Resultado.Text = '';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EscolherRedeLabel = uilabel(app.UIFigure);
            app.EscolherRedeLabel.Text = 'Escolher Rede';
            app.EscolherRede = uidropdown(app.UIFigure);
            app.EscolherRede.Items = {};
            app.EscolherRede.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = desenho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end